classdef DAB_Modulation_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        Label                        matlab.ui.control.Label
        DualActiveBridgePWMExplorationLabel  matlab.ui.control.Label
        Sigma_2Slider                matlab.ui.control.Slider
        Sigma_2SliderLabel           matlab.ui.control.Label
        Sigma_1Slider                matlab.ui.control.Slider
        Sigma_1SliderLabel           matlab.ui.control.Label
        DeltaSlider                  matlab.ui.control.Slider
        DeltaSliderLabel             matlab.ui.control.Label
        V2EditField                  matlab.ui.control.NumericEditField
        V2EditFieldLabel             matlab.ui.control.Label
        V1EditField                  matlab.ui.control.NumericEditField
        V1EditFieldLabel             matlab.ui.control.Label
        TurnsRatioEditField          matlab.ui.control.NumericEditField
        TurnsRatioEditFieldLabel     matlab.ui.control.Label
        InductanceEditField          matlab.ui.control.NumericEditField
        InductanceEditFieldLabel     matlab.ui.control.Label
        SwitchingFrequencyEditField  matlab.ui.control.NumericEditField
        SwitchingFrequencyEditFieldLabel  matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        UIAxes_IL                    matlab.ui.control.UIAxes
        UIAxes_VL                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        PlotTimer timer  % Description
        deltaTemp
        sigma1Temp
        sigma2Temp
    end
    
    methods (Access = private)
        
        function [IL] = GenerateIL(~,tvec,Vac1,Vac2,L)
        % calculates the current through the inductor based on voltages
            VL = Vac1-Vac2;
            
            ts = tvec(2)-tvec(1);
            IL = zeros(length(ts));
            
            for i = 2:length(tvec)
                IL(i) = IL(i-1)+VL(i-1)/L*ts;
            end   
            IL = IL-mean(IL);
        end

        function [Vac,tvec] = GenerateVac_TRAP(~,time,fswitch,delta,sigma,n)
        % generates the normalized voltage vector for a given PWM signal   
            T = 1/fswitch;       % period
            tvec = 0:T/n:time;   % time vector for pwm signal
            carrier = mod(tvec-delta/360*T,T)/T*360;
            
            modeA = carrier <= sigma;
            modeB = carrier > sigma & carrier <= 180 - sigma;
            modeC = carrier > 180-sigma & carrier <= 180+sigma;
            modeD = carrier > 180+sigma & carrier <= 360-sigma;
            modeE = carrier > 360-sigma;
            
            Vac = 0*(modeA+modeC+modeE) + 1*(modeB) -1*(modeD);
        end
    
        function Update_Plots(app,~,event)
            if ~nargin >2
                n = 50;
            else
                n = 1000;
            end
            
            Delta = app.deltaTemp;
            sigma1 = app.sigma1Temp;
            sigma2 = app.sigma2Temp;


            L = app.InductanceEditField.Value;
            f = app.SwitchingFrequencyEditField.Value;
            V1 = app.V1EditField.Value;
            V2 = app.V2EditField.Value;


            N = 2;
            t = 1/f*N;

            [Vac1,tvec] = GenerateVac_TRAP(app,t,f,0,sigma1,n);
            Vac1 = Vac1*V1;
            Vac2 = GenerateVac_TRAP(app,t,f,Delta,sigma2,n);
            Vac2 = Vac2*V2;
            IL = GenerateIL(app,tvec,Vac1,Vac2,L);

            maxI = max(V1,V2)/L*(1/f)/4;

            % Voltage Plots
            curAxes = app.UIAxes_VL;

            plot(curAxes,[0,t],[0 0],'Color',[0 0 0])
            hold(curAxes,"on");
            plot(curAxes,tvec,Vac1,'Color',[0 .8 0.2],'LineWidth',3);
            plot(curAxes,tvec,Vac2,'Color',[0 .4 0.8],'LineWidth',3);            
            hold(curAxes,"off")

            xlim(curAxes,[0,t])
            ylim(curAxes,max(V1,V2)*[-1.25,1.25])
            
            % Current Plots
            curAxes = app.UIAxes_IL;
            plot(curAxes,[0,t],[0 0],'Color',[0 0 0])
            hold(curAxes,"on");
            plot(curAxes,[0,t],[maxI maxI],'linestyle','--','Color',[0.5 0.5 0.5],'LineWidth',0.1)
            plot(curAxes,[0,t],[-maxI -maxI],'linestyle','--','Color',[0.5 0.5 0.5])
            plot(curAxes,tvec,IL,'LineWidth',3);
            hold(curAxes,"off");
            xlim(curAxes,[0,t])
            ylim(curAxes,maxI*[-1.25,1.25])
            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            V1 = 1500;
            V2 = 1600;
            f = 8e3;
            L = 75e-6;
            n = 1;
            
            app.InductanceEditField.Value = L;
            app.SwitchingFrequencyEditField.Value = f;
            app.V1EditField.Value = V1;
            app.V2EditField.Value = V2;
            app.TurnsRatioEditField.Value = n;
            
            app.deltaTemp = 0;
            app.sigma1Temp = 0;
            app.sigma2Temp = 0;

            app.PlotTimer = timer(...
            'ExecutionMode', 'fixedSpacing', ...      % Run timer repeatedly
            'Period', 0.1, ...                      
            'BusyMode', 'drop',...                % Queue timer callbacks when busy
            'TimerFcn', @app.Update_Plots);         % Callback that runs every period

            app.Update_Plots(app);


        end

        % Value changed function: DeltaSlider
        function DeltaSliderValueChanged(app, event)
            if strcmp(app.PlotTimer.Running,'on')
                stop(app.PlotTimer);
            end
            Update_Plots(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.PlotTimer);
            delete(app.PlotTimer);
            delete(app)             
        end

        % Value changing function: DeltaSlider
        function DeltaSliderValueChanging(app, event)
            if strcmp(app.PlotTimer.Running,'off')
                start(app.PlotTimer);
            end
            app.deltaTemp = event.Value;
        end

        % Value changed function: Sigma_1Slider
        function Sigma_1SliderValueChanged(app, event)
            if strcmp(app.PlotTimer.Running,'on')
                stop(app.PlotTimer);
            end
            Update_Plots(app);            
        end

        % Value changing function: Sigma_1Slider
        function Sigma_1SliderValueChanging(app, event)
            if strcmp(app.PlotTimer.Running,'off')
                start(app.PlotTimer);
            end
            app.sigma1Temp = event.Value;            
        end

        % Value changed function: Sigma_2Slider
        function Sigma_2SliderValueChanged(app, event)
            if strcmp(app.PlotTimer.Running,'on')
                stop(app.PlotTimer);
            end
            Update_Plots(app);            
        end

        % Value changing function: Sigma_2Slider
        function Sigma_2SliderValueChanging(app, event)
            if strcmp(app.PlotTimer.Running,'off')
                start(app.PlotTimer);
            end
            app.sigma2Temp = event.Value;            
        end

        % Value changed function: V1EditField
        function V1EditFieldValueChanged(app, event)
%             value = app.V1EditField.Value;
            Update_Plots(app);  
        end

        % Value changed function: V2EditField
        function V2EditFieldValueChanged(app, event)
%             value = app.V2EditField.Value;
            Update_Plots(app);  
        end

        % Value changed function: SwitchingFrequencyEditField
        function SwitchingFrequencyEditFieldValueChanged(app, event)
            Update_Plots(app); 
            
        end

        % Value changed function: InductanceEditField
        function InductanceEditFieldValueChanged(app, event)
            Update_Plots(app); 
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {677, 677};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {502, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SwitchingFrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.SwitchingFrequencyEditFieldLabel.Text = 'Switching Frequency';
            app.SwitchingFrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SwitchingFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @SwitchingFrequencyEditFieldValueChanged, true);
            app.InductanceEditFieldLabel = uilabel(app.LeftPanel);
            app.InductanceEditFieldLabel.Text = 'Inductance';
            app.InductanceEditField = uieditfield(app.LeftPanel, 'numeric');
            app.InductanceEditField.ValueChangedFcn = createCallbackFcn(app, @InductanceEditFieldValueChanged, true);
            app.TurnsRatioEditFieldLabel = uilabel(app.LeftPanel);
            app.TurnsRatioEditFieldLabel.Enable = 'off';
            app.TurnsRatioEditFieldLabel.Text = 'Turns Ratio';
            app.TurnsRatioEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TurnsRatioEditField.Editable = 'off';
            app.TurnsRatioEditField.Enable = 'off';
            app.TurnsRatioEditField.Value = 1;
            app.V1EditFieldLabel = uilabel(app.LeftPanel);
            app.V1EditFieldLabel.Text = 'V1';
            app.V1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.V1EditField.ValueChangedFcn = createCallbackFcn(app, @V1EditFieldValueChanged, true);
            app.V2EditFieldLabel = uilabel(app.LeftPanel);
            app.V2EditFieldLabel.Text = 'V2';
            app.V2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.V2EditField.ValueChangedFcn = createCallbackFcn(app, @V2EditFieldValueChanged, true);
            app.DeltaSliderLabel = uilabel(app.LeftPanel);
            app.DeltaSliderLabel.Text = 'Delta';
            app.DeltaSlider = uislider(app.LeftPanel);
            app.DeltaSlider.Limits = [0 90];
            app.DeltaSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90];
            app.DeltaSlider.ValueChangedFcn = createCallbackFcn(app, @DeltaSliderValueChanged, true);
            app.DeltaSlider.ValueChangingFcn = createCallbackFcn(app, @DeltaSliderValueChanging, true);
            app.Sigma_1SliderLabel = uilabel(app.LeftPanel);
            app.Sigma_1SliderLabel.Text = 'Sigma_1';
            app.Sigma_1Slider = uislider(app.LeftPanel);
            app.Sigma_1Slider.Limits = [0 90];
            app.Sigma_1Slider.MajorTicks = [0 10 20 30 40 50 60 70 80 90];
            app.Sigma_1Slider.ValueChangedFcn = createCallbackFcn(app, @Sigma_1SliderValueChanged, true);
            app.Sigma_1Slider.ValueChangingFcn = createCallbackFcn(app, @Sigma_1SliderValueChanging, true);
            app.Sigma_2SliderLabel = uilabel(app.LeftPanel);
            app.Sigma_2SliderLabel.Text = 'Sigma_2';
            app.Sigma_2Slider = uislider(app.LeftPanel);
            app.Sigma_2Slider.Limits = [0 90];
            app.Sigma_2Slider.MajorTicks = [0 10 20 30 40 50 60 70 80 90];
            app.Sigma_2Slider.ValueChangedFcn = createCallbackFcn(app, @Sigma_2SliderValueChanged, true);
            app.Sigma_2Slider.ValueChangingFcn = createCallbackFcn(app, @Sigma_2SliderValueChanging, true);
            app.DualActiveBridgePWMExplorationLabel = uilabel(app.LeftPanel);
            app.DualActiveBridgePWMExplorationLabel.Text = 'Dual Active Bridge PWM Exploration';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = 'Copyright 2023 The MathWorks, Inc.';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_VL = uiaxes(app.RightPanel);
            title(app.UIAxes_VL, 'Modulation Voltages')
            xlabel(app.UIAxes_VL, 'Time (s)')
            ylabel(app.UIAxes_VL, 'Voltage (s)')
            zlabel(app.UIAxes_VL, 'Z')
            app.UIAxes_IL = uiaxes(app.RightPanel);
            title(app.UIAxes_IL, 'Inductor Current')
            xlabel(app.UIAxes_IL, 'Time (s)')
            ylabel(app.UIAxes_IL, 'Current (A)')
            zlabel(app.UIAxes_IL, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAB_Modulation_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end